classdef PlayingMode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Button_4                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        PlotshuttlecockButton          matlab.ui.control.Button
        ShootingButton                 matlab.ui.control.Button
        ChoosetheshootingpostionLabel  matlab.ui.control.Label
        RightcourtButton               matlab.ui.control.Button
        MiddlecourtButton              matlab.ui.control.Button
        LeftcourtButton                matlab.ui.control.Button
        PlayingmodeLabel               matlab.ui.control.Label
        AngleEditField                 matlab.ui.control.NumericEditField
        AngleEditFieldLabel            matlab.ui.control.Label
        InitialvelocitymsEditField     matlab.ui.control.NumericEditField
        InitialvelocitymsLabel         matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = public)
        xPos double
        angle double
        velocity double
         robot = RacketBot(transl(-1,1.5,0.5));
         bricknum 
         f
         v
         data
         vertexColours
         BrickVertexCount
         BrickMesh_h
         g
         theta
         t
         tEnd
         x
         y
         z
         tr
         dist
         d_min
         counter
         num
         qHit = [-0.75 deg2rad([0 0 0 -90 0 90])];
         T1
         T2
         M
         %q1
         %q2
         qNow
         qTraj
         steps
         x1
         x2
         deltaT
         xR
         s
         xdot
         qMatrix
         T
         brick
         J
         qdot

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            assignin("base","velocity",app.InitialvelocitymsEditField.Value);
            assignin("base","angle",app.AngleEditField.Value);
            assignin("base","xPos",app.xPos);
            assignin("base","r",app.robot);
                       
            %app.delete
        end

        % Button pushed function: LeftcourtButton
        function LeftcourtButtonPushed(app, event)
            app.xPos = -0.65;
        end

        % Button pushed function: MiddlecourtButton
        function MiddlecourtButtonPushed(app, event)
            app.xPos = 0;
        end

        % Button pushed function: RightcourtButton
        function RightcourtButtonPushed(app, event)
            app.xPos = 1.25;
        end

        % Button pushed function: ShootingButton
        function ShootingButtonPushed(app, event)
            app.g = 9.81;
            app.angle = app.AngleEditField;
            app.theta = deg2rad(app.angle);
            app.velocity = app.InitialvelocitymsEditField;
            app.tEnd = 2*app.velocity*sin(app.theta)/app.g;

            app.t = linspace(0,app.tEnd);

            app.y = app.velocity*app.t*cos(app.theta)-1;
            app.z = 0.1+app.velocity*app.t*sin(app.theta)-0.5*app.g*(app.t).^2;
            app.x = app.xPos + 0.*app.t;


            % Find the contact point => num
            app.tr = app.robot.model.fkine(app.robot.model.getpos).T;
            app.dist = zeros(100,1);
            for i = 1:100
                %x((1,i)-tr(1,4))^2
                %(z(1,i)-tr(3,4))^2
                app.dist(i,1) = sqrt(   (app.y(1,i)-app.tr(2,4))^2  );
                app.d_min = min(app.dist);
            end

            app.counter = 1;
            for i = 1:100
                if app.dist(i,1) - app.d_min == 0
                    app.num = app.counter;
                end
                app.counter = app.counter + 1;
            end
            
            % Move the end-effector to that point

            app.T1 = [eye(3) [app.x(1,app.num) app.y(1,app.num) app.z(1,app.num)-0.1]'; zeros(1,3) 1]; %Pose when contact the shuttle cock

            app.T2 = [eye(3) [app.x(1,app.num) app.y(1,app.num+6) app.z(1,app.num+6)-0.1]'; zeros(1,3) 1]; %Swing pose

            app.M = [1 1 1 1 1 1]; %masking matrix

            q1 = app.robot.model.ikcon(app.T1,app.qHit);                    % Solve for joint angles
            q2 = app.robot.model.ikcon(app.T2,app.qHit);

            % Move to swing pose
            app.qNow = app.robot.model.getpos();
            app.qTraj = jtraj(app.qNow,app.q1,50);
            for i = 1:50
                app.robot.model.animate(app.qTraj(i,:));
                %T0 = r.model.fkine(qMatrix0(i,:)).T;
                %line1 = plot3(T0(1,4), T0(2,4), T0(3,4), '*');
                pause(0.01);
            end
            % Swing pose by RMRC-Resolved motion rate control => qMatrix

            app.steps = app.num;

            app.x1 = [app.x(1,app.num) app.y(1,app.num) (app.z(1,app.num)-0.1) 0 0 0]';
            app.x2 = [app.x(1,app.num) app.y(1,app.num+6) (app.z(1,app.num+6)-0.1) 0 0 0]';
            app.deltaT = 0.05;                                        % Discrete time step

            app.xR = zeros(6,app.steps);

            app.s = lspb(0,1,app.steps);                                 % Create interpolation scalar
            for i = 1:app.steps
                app.xR(:,i) = app.x1*(1-app.s(i)) + app.s(i)*app.x2;

            end

            app.qMatrix = [nan(app.steps,6) zeros(app.steps,1)];

            app.qMatrix(1,:) = app.q1;
            %r.model.ikcon(T1);

            for i = 1:app.steps-1
                app.xdot = (app.xR(:,i+1) - app.xR(:,i))/app.deltaT;
                app.J = app.robot.model.jacob0(app.qMatrix(i,:));
                app.J = app.J(1:6,1:6);
                app.qdot = inv(app.J)*app.xdot;                             % Solve velocitities via RMRC
                app.qMatrix(i+1,1:4) =  app.qMatrix(i,1:4) + app.deltaT*app.qdot(1:4,1)';

                app.qMatrix(i+1,5) = deg2rad(-80)-deg2rad(90)/68*i;
                app.qMatrix(i+1,6) = 0;
                app.qMatrix(i+1,7) = pi/2;

            end
            app.counter = app.num;
            for i=1:app.num
                brick = transl(x(1,i),y(1,i),z(1,i))*trotx(i*pi/100);

                BrickPose = brick ;

                %MOVE THE BRICK. Update the point then multiply it to the vertices
                UpdatedPoints = [BrickPose * [v,ones(BrickVertexCount,1)]']';

                %The vertices are all the rows and 1 to 3 columns of the UpdatedPoints
                BrickMesh_h.Vertices = UpdatedPoints(:,1:3);


                r.model.animate(qMatrix(counter,:));
                T = r.model.fkine(qMatrix(counter,:)).T;
                line1 = plot3(T(1,4), T(2,4), T(3,4),'o','MarkerSize',1,'Color','m');

                counter = counter - 1;

                h = plot3(app.x(1,i),y(1,i),z(1,i),'o','MarkerSize',3,'Color','r');

                pause(0.01);

            end

            try delete(h);
            catch Me
            end
            % Shuttlecock by backward
            app.counter = 1;
            for i=app.num:-1:1
                app.brick = transl(app.x(1,i),app.y(1,i),app.z(1,i))*trotx(i*pi/100);

                BrickPose = app.brick ;

                %MOVE THE BRICK. Update the point then multiply it to the vertices
                UpdatedPoints = [BrickPose * [app.v,ones(app.BrickVertexCount,1)]']';

                %The vertices are all the rows and 1 to 3 columns of the UpdatedPoints
                app.BrickMesh_h.Vertices = UpdatedPoints(:,1:3);


                app.robot.model.animate(app.qMatrix(app.counter,:));
                app.T = app.robot.model.fkine(app.qMatrix(app.counter,:)).T;
                %line1 = plot3(T(1,4), T(2,4), T(3,4), '*');

                app.counter = app.counter + 1;

                pause(0.005);
            end

            % Move back to the initial pose
            qCurrent =app.robot.model.getpos();
            app.qTraj = jtraj(qCurrent,zeros(1,7),50);
            for i = 1:50
                app.robot.model.animate(app.qTraj(i,:));
                pause(0.01);
            end


        end

        % Button pushed function: PlotshuttlecockButton
        function PlotshuttlecockButtonPushed(app, event)
            app.bricknum = [app.xPos,-1.5,0.1];
            [app.f,app.v,app.data] = plyread('shuttleCock.PLY','tri');
            app.vertexColours = [app.data.vertex.red, app.data.vertex.green, app.data.vertex.blue] / 255;
            app.BrickVertexCount = size(app.v,1);
            app.BrickMesh_h = trisurf(app.f,app.v(:,1)+app.bricknum(1,1),app.v(:,2)+app.bricknum(1,2), app.v(:,3)+app.bricknum(1,3) ...
                ,'FaceVertexCData',app.vertexColours,'EdgeColor','none','EdgeLighting','none');
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.AngleEditField = 60
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.InitialvelocitymsEditField = 6
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'background.jpg');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.InitialvelocitymsLabel = uilabel(app.UIFigure);
            app.InitialvelocitymsLabel.Text = 'Initial velocity (m/s)';
            app.InitialvelocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle ';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlayingmodeLabel = uilabel(app.UIFigure);
            app.PlayingmodeLabel.Text = 'Playing mode';
            app.LeftcourtButton = uibutton(app.UIFigure, 'push');
            app.LeftcourtButton.ButtonPushedFcn = createCallbackFcn(app, @LeftcourtButtonPushed, true);
            app.LeftcourtButton.Text = 'Left court';
            app.MiddlecourtButton = uibutton(app.UIFigure, 'push');
            app.MiddlecourtButton.ButtonPushedFcn = createCallbackFcn(app, @MiddlecourtButtonPushed, true);
            app.MiddlecourtButton.Text = 'Middle court';
            app.RightcourtButton = uibutton(app.UIFigure, 'push');
            app.RightcourtButton.ButtonPushedFcn = createCallbackFcn(app, @RightcourtButtonPushed, true);
            app.RightcourtButton.Text = 'Right court';
            app.ChoosetheshootingpostionLabel = uilabel(app.UIFigure);
            app.ChoosetheshootingpostionLabel.Text = 'Choose the shooting postion';
            app.ShootingButton = uibutton(app.UIFigure, 'push');
            app.ShootingButton.ButtonPushedFcn = createCallbackFcn(app, @ShootingButtonPushed, true);
            app.ShootingButton.Text = 'Shooting';
            app.PlotshuttlecockButton = uibutton(app.UIFigure, 'push');
            app.PlotshuttlecockButton.ButtonPushedFcn = createCallbackFcn(app, @PlotshuttlecockButtonPushed, true);
            app.PlotshuttlecockButton.Text = 'Plot shuttlecock';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '6';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '6.2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '6.5';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '60';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlayingMode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end